classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CodageetDcodageLZ77UIFigure  matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        DcodageCheckBox              matlab.ui.control.CheckBox
        CodageCheckBox               matlab.ui.control.CheckBox
        ChoisiruneoptionLabel        matlab.ui.control.Label
        DcodageButtonGroup           matlab.ui.container.ButtonGroup
        ConvertirlefichierBINenImageButton_2  matlab.ui.control.ToggleButton
        ConvertirlefichierBINentexteButton_2  matlab.ui.control.ToggleButton
        CodageButtonGroup            matlab.ui.container.ButtonGroup
        CoderuneimageButton_2        matlab.ui.control.ToggleButton
        CoderunfichiertexteButton_2  matlab.ui.control.ToggleButton
        SaisirletextecoderButton_2   matlab.ui.control.ToggleButton
        YassineELHAMMOUMILabel       matlab.ui.control.Label
        AkramCHRAIBILabel            matlab.ui.control.Label
        TailledelasquencenbdebitsSpinner  matlab.ui.control.Spinner
        TailledelasquencenbdebitsSpinnerLabel  matlab.ui.control.Label
        TailledupointeurnbdebitsSpinner  matlab.ui.control.Spinner
        TailledupointeurnbdebitsSpinnerLabel  matlab.ui.control.Label
        ParamtresducodeLabel         matlab.ui.control.Label
        AlgorithmeLZ77Label          matlab.ui.control.Label
        SoumettreButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        pointerBits
        lengthBits
        codage = ['Saisir le texte à coder','Coder un fichier texte','Coder une image'];
    end
    
    methods (Access = public)
        

    end


    methods (Access = private)
        
        function hexString = binaryToHex(app,binaryString)
    % Ensure binaryString is a row vector
    binaryString = binaryString(:)'; 
    
    % Pad with leading zeros to make its length a multiple of 4
    while mod(length(binaryString), 4) ~= 0
        binaryString = ['0', binaryString];
    end

    % Group into 4-bit chunks
    numChunks = length(binaryString) / 4;
    hexString = '';
    
    for i = 1:numChunks
        % Extract 4 bits
        fourBits = binaryString((i-1)*4 + 1:i*4);
        % Convert 4 bits to decimal, then to hex
        hexChar = dec2hex(bin2dec(fourBits));
        % Append the hex character
        hexString = [hexString, hexChar]; %#ok<AGROW>
    end

        end
    
        
        function decodedSeq = deco(app,R, offsetBits, lengthBits)
    % Function to decode the LZ77 compressed sequence with variable offset and length bits
    % Inputs:
    % R - The compressed binary sequence (cell array)
    % offsetBits - Number of bits used for encoding the offset
    % lengthBits - Number of bits used for encoding the match length
    
    % Initialize the output decoded sequence
    decodedSeq = '';
    
    % Process each token in the compressed sequence
    for idx = 1:length(R)
        % Convert cell array element to string
        currentToken = char(R{idx});
        
        % Literal token (starts with '1')
        if currentToken(1) == '1'
            % Extract 8-bit ASCII representation
            literalChar = char(bin2dec(currentToken(2:end)));
            decodedSeq = [decodedSeq, literalChar];
        
        % Match token (starts with '0')
        else
            % Extract offset and length
            offsetBin = currentToken(2:1+offsetBits);
            lengthBin = currentToken(2+offsetBits:end);
            
            % Convert binary to decimal
            offset = bin2dec(offsetBin);
            matchLength = bin2dec(lengthBin);
            
            % Validate match parameters
            if offset > 0 && offset <= length(decodedSeq) && ...
               matchLength > 0 && (length(decodedSeq) - offset + matchLength) <= length(decodedSeq)
                % Get the matched substring
                matchStart = length(decodedSeq) - offset + 1;
                matchedString = decodedSeq(matchStart:matchStart + matchLength - 1);
                
                % Append the matched substring
                decodedSeq = [decodedSeq, matchedString];
            else
                % Skip invalid match and provide a warning
                warning('Skipping invalid match parameters: offset=%d, length=%d, decoded sequence length=%d', ...
                    offset, matchLength, length(decodedSeq));
            end
        end
    end

        end
    
        
        function R = binaryStringToCompressedCellArray(app,binaryString, offsetBits, lengthBits)
    % Function to convert a binary text string to a cell array of compressed binary tokens
    % Inputs: 
    %   binaryString - A continuous binary string representing the compressed sequence
    %   offsetBits - Number of bits used for offset encoding
    %   lengthBits - Number of bits used for length encoding
    % Output:
    %   R - A cell array where each cell contains a compressed binary token

    % Initialize empty cell array
    R = {};
    
    % Index to track current position in the binary string
    currentIdx = 1;
    
    % Continue until we have processed the entire binary string
    while currentIdx <= length(binaryString)
        % Check first bit to determine token type
        if binaryString(currentIdx) == '1'
            % Literal token: 1 bit flag + 8 bits for character
            token = binaryString(currentIdx:currentIdx+8);
            R{end+1} = token;
            currentIdx = currentIdx + 9;
        else
            % Match token: 1 bit flag + offsetBits + lengthBits
            tokenLength = 1 + offsetBits + lengthBits;
            token = binaryString(currentIdx:currentIdx+tokenLength-1);
            R{end+1} = token;
            currentIdx = currentIdx + tokenLength;
        end
    end
        end
    
        
        function [R, resulseq, repeatedCount, uniqueCount] = enco(app,seq, offsetBits, lengthBits)
    % Function inputs:
    % seq        - The input sequence to compress
    % offsetBits - Number of bits for encoding the offset
    % lengthBits - Number of bits for encoding the length of a match
    
    % Initialize variables
    R = {};           % Compressed binary representation
    L = []; P = [];   % Temporary arrays for match lengths and positions
    A = []; B = [];   % Temporary arrays for selecting best matches
    C = []; D = [];   % Final chosen match parameters
    resulseq = '';    % Human-readable compressed representation
    repeatedCount = 0; % Counter for the number of repeated sequences (matches)
    uniqueCount = 0;   % Counter for unique characters
    N = 0;             % Flag to indicate a match was found

    % Maximum values for offset and length based on bits
    maxOffset = 2^offsetBits - 1;
    maxLength = 2^lengthBits - 1;

    % Add the first character as a literal
    R = [R, num2str(strcat('1', num2str(dec2bin(double(seq(1)), 8))))];
    resulseq = [resulseq, seq(1)];
    uniqueCount = uniqueCount + 1;

    % Add the second character if the sequence has at least 2 characters
    if length(seq) >= 2
        R = [R, num2str(strcat('1', num2str(dec2bin(double(seq(2)), 8))))];
        resulseq = [resulseq, seq(2)];
        uniqueCount = uniqueCount + 1;
    end

    % Start processing from the third character
    i = 3;
    while i <= length(seq)
        N = 1;
        L = []; P = []; % Reset match length and position arrays
        
        % Look for matches in the sliding window
        for k = 1:i-1
            for j = i+1:length(seq)
                if k+j-i < i && isequal(seq(i:j), seq(k:k+j-i))
                    if length(seq(i:j)) <= maxLength && i-k <= maxOffset
                        L = [L, length(seq(i:j))];
                        P = [P, i-k];
                    end
                else
                    break
                end
            end
        end

        % If matches are found, choose the best one
        if ~isempty(L) && ~isempty(P)
            % Find the longest match
            maxLengthIdx = find(L == max(L), 1, 'first');
            chosenLength = L(maxLengthIdx);
            chosenOffset = P(maxLengthIdx);

            % Encode the match
            if chosenOffset <= maxOffset && chosenLength <= maxLength
                R = [R, strcat('0', num2str(dec2bin(chosenOffset, offsetBits)), num2str(dec2bin(chosenLength, lengthBits)))];
                resulseq = [resulseq, strcat('<', '0', ',', num2str(chosenOffset), ',', num2str(chosenLength), '>')];
                repeatedCount = repeatedCount + 1; % Increment match count
                i = i + chosenLength; % Skip the matched sequence
                N = 0; % A match was found and encoded
            end
        end

        % If no match is found, encode the character as a literal
        if N == 1
            R = [R, num2str(strcat('1', num2str(dec2bin(double(seq(i)), 8))))];
            resulseq = [resulseq, seq(i)];
            uniqueCount = uniqueCount + 1; % Increment unique count
            i = i + 1;
        end
    end

    % Outputs:
    % - R: Compressed binary sequence
    % - resulseq: Human-readable compressed sequence
    % - repeatedCount: Total number of repeated sequences (matches)
    % - uniqueCount: Total number of unique (non-repeated) characters

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SaisirletextecoderButton_2.Value = false;
            app.CoderunfichiertexteButton_2.Value = false;
            app.CoderuneimageButton_2.Value = false;
        end

        % Value changed function: TailledupointeurnbdebitsSpinner
        function TailledupointeurnbdebitsSpinnerValueChanged(app, event)
            value = app.TailledupointeurnbdebitsSpinner.Value;
            app.pointerBits = value
        end

        % Value changed function: TailledelasquencenbdebitsSpinner
        function TailledelasquencenbdebitsSpinnerValueChanged(app, event)
            value = app.TailledelasquencenbdebitsSpinner.Value;
            app.lengthBits = value
        end

        % Button pushed function: SoumettreButton
        function SoumettreButtonPushed(app, event)
            if app.CodageCheckBox.Value == true
                if app.CodageButtonGroup.SelectedObject.Text == "Saisir le texte à coder"
                % Entrée utilisateur pour le texte
                
                codedseqcell = {}; 
                humanvisual = '';
                repeatedCount = 0;
                uniqueCount = 0;

                text = inputdlg('Saisissez le texte à compresser :', 'Compression de texte');
                if isempty(text)
                    return;
                end
                text = [text{:}]
                % Codage LZ77
                [codedseqcell,humanvisual, repeatedCount, uniqueCount] = enco(app,text, app.pointerBits, app.lengthBits);
                % Enregistrer les données codées
                s = [codedseqcell{:}];
                Hexa = BinaryToHex(s); % conversion en hexadecimal
                % Affichage des résultats
                originalBits = strlength(text)*8;
                compressedBits = repeatedCount * (1 + app.pointerBits + app.lengthBits) + uniqueCount * 9 
                compressionRatio = (originalBits / compressedBits);
                msgbox(sprintf('Compression réussie !\nSéquence codée : %s\nEn Hexadécimal : %s\nBits originaux : %d\nBits compressés : %d\nTaux de compression : %f', ...
                    humanvisual,Hexa,originalBits, compressedBits, compressionRatio));


                elseif app.CodageButtonGroup.SelectedObject.Text == "Coder un fichier texte"
                    [FileName,PathName] = uigetfile("*.txt","Veuillez selectionner un Fichier .txt")
                    path = fullfile(PathName, FileName);
                    text = readlines(path);
                    lines =[text{:}]
                    [codedseqcell,humanvisual, repeatedCount, uniqueCount] = enco(app,lines, app.pointerBits, app.lengthBits);
                    s = [codedseqcell{:}];
                    % Prompt the user to select a directory
                    selectedDir = uigetdir('Select a directory to save the file');
                    if isequal(selectedDir, 0)
                        disp('User canceled the directory selection.');
                        return;
                    end
                    
                    % Construct the full path for the .bin file
                    fileName = 'output.bin';
                    fullFilePath = fullfile(selectedDir, fileName);
                    
                    % Save the string as a .bin file
                    fileID = fopen(fullFilePath, 'wb'); % Open file in binary write mode
                    if fileID == -1
                        error('Could not open file for writing.');
                    end
                    fwrite(fileID, s, 'char'); % Write the string as binary data
                    fclose(fileID); % Close the file
                    
                    disp(['File successfully saved at: ', fullFilePath]);


                    Hexa = BinaryToHex(s); % conversion en hexadecimal
                    % Affichage des résultats
                    originalBits = strlength(lines)*8;
                    compressedBits = repeatedCount * (1 + app.pointerBits + app.lengthBits) + uniqueCount * 9 
                    compressionRatio = (originalBits / compressedBits);
                    msgbox(sprintf('Compression réussie !\nSéquence codée : %s\nEn Hexadécimal : %s\nBits originaux : %d\nBits compressés : %d\nTaux de compression : %f', ...
                    humanvisual,Hexa,originalBits, compressedBits, compressionRatio));
           

                elseif app.CodageButtonGroup.SelectedObject.Text == "Coder une image"
                    [FileName,PathName] = uigetfile("*","Veuillez selectionner un Fichier .jpg");
                    path = fullfile(PathName, FileName);
                    img = imread(path);
                    img = im2gray(img);
                    [length,width] = size(img);
                    
                    % Calculate original bits BEFORE flattening
                    originalBits = length * width * 8;
                    
                    img = img';
                    img = img(:);
                    img = img';
                    
                    
                    [codedseqcell,humanvisual, repeatedCount, uniqueCount] = enco(app,img, app.pointerBits, app.lengthBits);
                    
                    % Convert dimensions to 32-bit binary
                    length = dec2bin(length,16);
                    width = dec2bin(width,16);

                    codedseqcell =[codedseqcell, { length },{ width }]
                    s = [codedseqcell{:}];


                    % Prompt the user to select a directory
                    selectedDir = uigetdir('Select a directory to save the file');
                    if isequal(selectedDir, 0)
                        disp('User canceled the directory selection.');
                        return;
                    end
                    
                    % Construct the full path for the .bin file
                    fileName = 'output.bin';
                    fullFilePath = fullfile(selectedDir, fileName);
                    
                    % Save the string as a .bin file
                    fileID = fopen(fullFilePath, 'wb'); % Open file in binary write mode
                    if fileID == -1
                        error('Could not open file for writing.');
                    end
                    fwrite(fileID, s, 'char'); % Write the string as binary data
                    fclose(fileID); % Close the file
                    
                    Hexa = BinaryToHex(s); % conversion en hexadecimal
                    
                    % Calculation of compressed bits
                    compressedBits = repeatedCount * (1 + app.pointerBits + app.lengthBits) + uniqueCount * 9;
                    compressionRatio = (originalBits / compressedBits);
                    msgbox(sprintf('Compression réussie !\nEn Hexadécimal : %s\nBits originaux : %d\nBits compressés : %d\nTaux de compression : %f', ...
                        Hexa, originalBits, compressedBits, compressionRatio));     
                end
            end   


            if app.DcodageCheckBox.Value == true
                if app.DcodageButtonGroup.SelectedObject.Text == "Convertir le fichier BIN en texte"
                    [FileName,PathName] = uigetfile("*.bin","Veuillez selectionner un Fichier .bin")
                    path = fullfile(PathName, FileName);
                    bin = readlines(path);
                    bin = [bin{:}]
                    bin = binaryStringToCompressedCellArray(app,bin,app.pointerBits,app.lengthBits);
                    R=deco(app,bin,app.pointerBits,app.lengthBits);
                    msgbox(sprintf('Décompression réussie !\nTexte décodé :%s',R))
                elseif app.DcodageButtonGroup.SelectedObject.Text == "Convertir le fichier BIN en Image"
                    [FileName,PathName] = uigetfile("*.bin","Veuillez selectionner un Fichier .bin")
                    path = fullfile(PathName, FileName);
                    bin = readlines(path);
                    bin = [bin{:}];

                    wid = bin(end-15:end);
                    leng = bin(end-31:end-16);

                    wid = bin2dec(wid);
                    leng = bin2dec(leng);

                    bin = bin(1:end-32);


                    bin = binaryStringToCompressedCellArray(app,bin,app.pointerBits,app.lengthBits);

                    R=deco(app,bin,app.pointerBits,app.lengthBits);
                    R=uint8(R);

                    R = reshape(R,[wid,leng]);
                    R = R';
                    imshow(R);

                end
            end

        end

        % Selection changed function: CodageButtonGroup
        function CodageButtonGroupSelectionChanged(app, event)
            selectedButton = app.CodageButtonGroup.SelectedObject;
            
        end

        % Selection changed function: DcodageButtonGroup
        function DcodageButtonGroupSelectionChanged(app, event)
            selectedButton = app.DcodageButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CodageetDcodageLZ77UIFigure = uifigure('Visible', 'off');
            app.CodageetDcodageLZ77UIFigure.Name = 'Codage et Décodage LZ77';
            app.GridLayout = uigridlayout(app.CodageetDcodageLZ77UIFigure);
            app.SoumettreButton = uibutton(app.GridLayout, 'push');
            app.SoumettreButton.ButtonPushedFcn = createCallbackFcn(app, @SoumettreButtonPushed, true);
            app.SoumettreButton.Text = 'Soumettre';
            app.AlgorithmeLZ77Label = uilabel(app.GridLayout);
            app.AlgorithmeLZ77Label.Text = 'Algorithme LZ77';
            app.ParamtresducodeLabel = uilabel(app.GridLayout);
            app.ParamtresducodeLabel.Text = 'Paramètres du code :';
            app.TailledupointeurnbdebitsSpinnerLabel = uilabel(app.GridLayout);
            app.TailledupointeurnbdebitsSpinnerLabel.Text = 'Taille du pointeur (nb de bits):';
            app.TailledupointeurnbdebitsSpinner = uispinner(app.GridLayout);
            app.TailledupointeurnbdebitsSpinner.ValueChangedFcn = createCallbackFcn(app, @TailledupointeurnbdebitsSpinnerValueChanged, true);
            app.TailledelasquencenbdebitsSpinnerLabel = uilabel(app.GridLayout);
            app.TailledelasquencenbdebitsSpinnerLabel.Text = 'Taille de la séquence (nb de bits):';
            app.TailledelasquencenbdebitsSpinner = uispinner(app.GridLayout);
            app.TailledelasquencenbdebitsSpinner.ValueChangedFcn = createCallbackFcn(app, @TailledelasquencenbdebitsSpinnerValueChanged, true);
            app.AkramCHRAIBILabel = uilabel(app.GridLayout);
            app.AkramCHRAIBILabel.Text = 'Akram CHRAIBI';
            app.YassineELHAMMOUMILabel = uilabel(app.GridLayout);
            app.YassineELHAMMOUMILabel.Text = 'Yassine EL HAMMOUMI';
            app.CodageButtonGroup = uibuttongroup(app.GridLayout);
            app.CodageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CodageButtonGroupSelectionChanged, true);
            app.CodageButtonGroup.Title = 'Codage';
            app.SaisirletextecoderButton_2 = uitogglebutton(app.CodageButtonGroup);
            app.SaisirletextecoderButton_2.Text = 'Saisir le texte à coder';
            app.SaisirletextecoderButton_2.Value = true;
            app.CoderunfichiertexteButton_2 = uitogglebutton(app.CodageButtonGroup);
            app.CoderunfichiertexteButton_2.Text = 'Coder un fichier texte';
            app.CoderuneimageButton_2 = uitogglebutton(app.CodageButtonGroup);
            app.CoderuneimageButton_2.Text = 'Coder une image';
            app.DcodageButtonGroup = uibuttongroup(app.GridLayout);
            app.DcodageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DcodageButtonGroupSelectionChanged, true);
            app.DcodageButtonGroup.Title = 'Décodage';
            app.ConvertirlefichierBINentexteButton_2 = uitogglebutton(app.DcodageButtonGroup);
            app.ConvertirlefichierBINentexteButton_2.Text = 'Convertir le fichier BIN en texte';
            app.ConvertirlefichierBINentexteButton_2.Value = true;
            app.ConvertirlefichierBINenImageButton_2 = uitogglebutton(app.DcodageButtonGroup);
            app.ConvertirlefichierBINenImageButton_2.Text = 'Convertir le fichier BIN en Image';
            app.ChoisiruneoptionLabel = uilabel(app.GridLayout);
            app.ChoisiruneoptionLabel.Text = 'Choisir une option :';
            app.CodageCheckBox = uicheckbox(app.GridLayout);
            app.CodageCheckBox.Text = 'Codage';
            app.DcodageCheckBox = uicheckbox(app.GridLayout);
            app.DcodageCheckBox.Text = 'Décodage';
            % Show the figure after all components are created
            app.CodageetDcodageLZ77UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CodageetDcodageLZ77UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CodageetDcodageLZ77UIFigure)
        end
    end
end